classdef LiveTracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        UIAxes    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
% Define the grid intervals
mic_positions = [0, 0;      % Position of mic1
                0, 0.5;    % Position of mic2
                0.8, 0;    % Position of mic3
                0.8, 0.5]; % Position of mic4
x_min = 0;
x_max = 0.8;
y_min = 0;
y_max = 0.5;
x_intervals = 0.1;
y_intervals = 0.1;

% Create the x and y values for the grid
x_values = x_min:x_intervals:x_max;
y_values = y_min:y_intervals:y_max;

% Initialize the figure and hold on
figure;
hold on;
grid on;
xticks(x_values);
yticks(y_values);

microphone_colors = 'k';  % Change to black color for microphones
microphone_radius = 1e-2;

for i = 1:size(mic_positions, 1)
    x = mic_positions(i, 1);
    y = mic_positions(i, 2);
    color = microphone_colors;
    
    % Draw microphone circles
    t = linspace(0, 2 * pi, 100);
    x_circle = microphone_radius * cos(t) + x;
    y_circle = microphone_radius * sin(t) + y;
    plot(x_circle, y_circle, 'Color', color, 'LineWidth', 2);
end

estimated_position_color = 'r';  % Change to red color for the estimated source
estimated_position_radius = 1e-2;
crosshair_length = 3 * estimated_position_radius;

% Define the path for the estimated source
path_points = [linspace(x_min, x_max, 10)', linspace(y_min, y_max, 10)'];

% Initialize an array to store all the estimated positions
all_estimated_positions = path_points;

% Simulate real-time tracking
for i = 1:size(path_points, 1)
    Triangulation_estimate = path_points(i, :);

    % Update the array of estimated positions
    all_estimated_positions = [all_estimated_positions; Triangulation_estimate];

    % Clear the previous source position and crosshair
    clf;

    % Replot the grid and microphones
    hold on;
    grid on;
    xticks(x_values);
    yticks(y_values);
    for j = 1:size(mic_positions, 1)
        x = mic_positions(j, 1);
        y = mic_positions(j, 2);
        color = microphone_colors;

        % Draw microphone circles
        t = linspace(0, 2 * pi, 100);
        x_circle = microphone_radius * cos(t) + x;
        y_circle = microphone_radius * sin(t) + y;
        plot(x_circle, y_circle, 'Color', color, 'LineWidth', 2);
    end

    % Draw all the source position circles
    for k = 1:size(all_estimated_positions, 1)
        t = linspace(0, 2 * pi, 100);
        x_circle = estimated_position_radius * cos(t) + all_estimated_positions(k, 1);
        y_circle = estimated_position_radius * sin(t) + all_estimated_positions(k, 2);
        plot(x_circle, y_circle, 'Color', estimated_position_color, 'LineWidth', 2);
    end

    % Draw a line connecting all estimated positions
    if size(all_estimated_positions, 1) > 1
        line(all_estimated_positions(:, 1), all_estimated_positions(:, 2), 'Color', estimated_position_color, 'LineWidth', 1);
    end

    % Add a red crosshair inside the most recent source position circle
    x_crosshair = [Triangulation_estimate(1) - crosshair_length / 2, Triangulation_estimate(1) + crosshair_length / 2];
    y_crosshair = [Triangulation_estimate(2), Triangulation_estimate(2)];
    plot(x_crosshair, y_crosshair, 'Color', 'b', 'LineWidth', 1);

    x_crosshair = [Triangulation_estimate(1), Triangulation_estimate(1)];
    y_crosshair = [Triangulation_estimate(2) - crosshair_length / 2, Triangulation_estimate(2) + crosshair_length / 2];
    plot(x_crosshair, y_crosshair, 'Color', 'b', 'LineWidth', 1);

    pause(0.5);  % Adjust the delay to control the animation speed
end

hold off;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Real-Time Grid')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LiveTracking

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end