classdef Grid_Simulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Microphone4PositionLabel        matlab.ui.control.Label
        Microphone3PositionLabel        matlab.ui.control.Label
        Microphone2PositionLabel        matlab.ui.control.Label
        Microphone1PositionLabel        matlab.ui.control.Label
        YvalueEditField_4               matlab.ui.control.NumericEditField
        YvalueEditField_4Label          matlab.ui.control.Label
        XvalueEditField_4               matlab.ui.control.NumericEditField
        XvalueEditField_4Label          matlab.ui.control.Label
        YvalueEditField_3               matlab.ui.control.NumericEditField
        YvalueEditField_3Label          matlab.ui.control.Label
        XvalueEditField_3               matlab.ui.control.NumericEditField
        XvalueEditField_3Label          matlab.ui.control.Label
        YvalueEditField_2               matlab.ui.control.NumericEditField
        YvalueEditField_2Label          matlab.ui.control.Label
        XvalueEditField_2               matlab.ui.control.NumericEditField
        XvalueEditField_2Label          matlab.ui.control.Label
        YvalueEditField                 matlab.ui.control.NumericEditField
        YvalueEditFieldLabel            matlab.ui.control.Label
        XvalueEditField                 matlab.ui.control.NumericEditField
        XvalueEditFieldLabel            matlab.ui.control.Label
        InitializeGridButton            matlab.ui.control.Button
        AcousticTriangulationSimulatorLabel  matlab.ui.control.Label
        CoordinateDisplayTextArea       matlab.ui.control.TextArea
        CoordinateDisplayTextAreaLabel  matlab.ui.control.Label
        DetermineSourceButton           matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonValueChanged(app, event)
            value = app.InitializeGridButton.Value;

% Calculate the error in X and Y coordinates
error_x = source_x - Estimated_position(1);
error_y = source_y - Estimated_position(2);

% Calculate the combined error using Euclidean distance
combined_error = sqrt(error_x^2 + error_y^2);

% Display the combined error
fprintf("Combined Error: %.3f\n", combined_error);

fprintf("Source position: X = %.3f  Y = %.3f\n",source_x,source_y);
fprintf("Triangulated source position estimation:  X = %.3f  Y = %.3f\n",Estimated_position(1),Estimated_position(2));
fprintf("Individual errors:  X = %.3f  Y = %.3f\n",error_x,error_y);
fprintf("Combined errors = %.3f\n",combined_error);
app.CoordinateDisplayTextArea.Value = sprintf("Actual source position: X = %.4f, Y = %.4f\nLookup table estimation: X = %.4f, Y = %.4f", source_x, source_y, Estimated_position(1), Estimated_position(2));
pause(1);

          % Define the grid intervals
            x_min = 0;
            x_max = 0.8;
            y_min = 0;
            y_max = 0.5;
            x_intervals = 0.1;
            y_intervals = 0.1;

            % Create the x and y values for the grid
            x_values = x_min:x_intervals:x_max;
            y_values = y_min:y_intervals:y_max;

            % Plot the grid lines
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            xticks(app.UIAxes, x_values);
            yticks(app.UIAxes, y_values);

            % Plot circles at microphone positions
            microphone_positions = [
                0, 0;
                0, 0.5;
                0.8, 0;
                0.8, 0.5
            ];
            microphone_colors = ['r', 'g', 'b', 'm'];
            microphone_radius = 1e-2;

            for i = 1:size(microphone_positions, 1)
                x = microphone_positions(i, 1);
                y = microphone_positions(i, 2);
                color = microphone_colors(i);
                
                % Draw circle
                t = linspace(0, 2*pi, 100);
                x_circle = microphone_radius * cos(t) + x;
                y_circle = microphone_radius * sin(t) + y;
                plot(app.UIAxes, x_circle, y_circle, 'Color', color, 'LineWidth', 2);
            end

            % Plot a circle at the estimated position
            %estimated_position = [0.4, 0.3];  % Replace with your estimated position
            estimated_position_color = 'c';
            estimated_position_radius = 1e-2;
            
            % Draw circle at estimated position
            t = linspace(0, 2*pi, 100);
            x_circle = estimated_position_radius * cos(t) + Estimated_position(1);
            y_circle = estimated_position_radius * sin(t) + Estimated_position(2);
            plot(app.UIAxes, x_circle, y_circle, 'Color', estimated_position_color, 'LineWidth', 2);

            hold(app.UIAxes, 'off');
        

        end

        % Button pushed function: DetermineSourceButton
        function DetermineSourceButtonPushed(app, event)
% Constants
c = 343; % Speed of sound in m/s
f = 440; % Frequency of source in Hz
Fs = 48000; % Sampling rate in Hz
%duration = 0.1; % Duration of the signal in seconds


% Microphone positions
mic = [0, 0;      % Mic 1
       0, 0.5;    % Mic 2
       0.8, 0;    % Mic 3
       0.8, 0.5]; % Mic 4
r1 = [0,0];                     %mic1
r2 = [0 0.5];                  %mic2
r3 = [0.8 0];                  %mic3              
r4 = [0.8,0.5];               %mic4
%LUT = Generate_LUT(mic(0),mic(1),mic(2),mic(3),100);     % Generate Look up table for TODA WRT mic 1
LUT = Generate_LUT(r1,r2,r3,r4,100);     % Generate Look up table for TODA WRT mic 1

% Simulated source position (for example, [0.4, 0.3])
source_x = rand()*0.8;
source_y = rand()*0.5;
source = [source_x,source_y];

   hchirp = dsp.Chirp( ...
    'InitialFrequency', 0,...
    'TargetFrequency', 10, ...
    'TargetTime', 10, ...
    'SweepTime', 100, ...
    'SampleRate', 50, ...
    'SamplesPerFrame', 500);

   chirpData = (step(hchirp))';

signal = chirpData;

t = (0:1/Fs:(length(signal) - 1) / Fs);

% Compute distances from the source to each microphone
d = sqrt(sum((mic - [source_x, source_y]).^2, 2));

% Calculate delays for each microphone
timeDelays = d/c;
delays = round((timeDelays) * Fs);

% Create delayed signals for each microphone
delayed_signals = zeros(4, length(signal) + max(delays));
figure;
subplot(5,1,1);
plot(t, signal);
title('Sound source - Original Chirp Signal ');
for i = 1:4
    delayed_signals(i, delays(i) + 1 : delays(i) + length(signal)) = signal;
    subplot(5, 1, i+1);
    plot(t, delayed_signals(i, 1:length(signal)));
    title(['Microphone ', num2str(i)]);
end

% Add white Gaussian noise to the signals
noise_level = 0.05; % Adjust as needed
noisy_signals = delayed_signals + noise_level*randn(size(delayed_signals));

figure;
TOA = zeros(1,4);
for i = 1:4
     % Compute the cross-correlation
    [cross_corr, delay_array] = xcorr(noisy_signals(i, :),signal);

    % Find the maximum peak in the cross-correlation (avoiding negative peaks)
    [max_peak, max_peak_idx] = max(cross_corr);

    % Check if the maximum peak is above a threshold
    if max_peak > 0.05 % Adjust the threshold as needed
        % Calculate the time difference using the index of the maximum peak
        TOA(i) = (delay_array(max_peak_idx) / Fs);
    else
        % Handle the case when no valid peak is found
        TOA(i) = NaN; % or assign some default value
    end
    
    subplot(4, 1, i);
    plot(delay_array / Fs, cross_corr);
    title(['Cross-correlation with Microphone ', num2str(i)]);
    xlabel('Time (s)');
    ylabel('Cross-correlation');
    grid on;
end


time_diffs = zeros(1,3);
for i = 2:4
   [cross_corr, lags] = xcorr(noisy_signals(1,:), noisy_signals(i,:));
%[cross_corr, lags] = xcorr(noisy_signals(i,:), noisy_signals(1,:)); % Swap the signals
    [~, idx] = max(cross_corr);
    time_diffs(i-1) = lags(idx) / Fs;
   
end

TDOA_21 = TOA(2) - TOA(1);
TDOA_31 = TOA(3) - TOA(1);
TDOA_41 = TOA(4) - TOA(1);
TDOA_32 = TOA(3) - TOA(2);
TDOA_42 = TOA(4) - TOA(2);
TDOA_43 = TOA(4) - TOA(3);


[Estimated_positionN1, Estimated_positionN2, Estimated_positionN3, Estimated_positionN4]   = triangulate(TDOA_21,TDOA_31,TDOA_41,TDOA_32,TDOA_42,TDOA_43,LUT);


possible_solutions = [];

% Check if the positions are valid and add them to possible_solutions
if (Estimated_positionN1(1) ~= 0 && Estimated_positionN1(2) ~= 0 && Estimated_positionN1(1) ~= r4(1) && Estimated_positionN1(2) ~= r4(2))
    possible_solutions(:, end+1) = Estimated_positionN1;
end

if (Estimated_positionN2(1) ~= r1(1) && Estimated_positionN2(2) ~= r1(1) && Estimated_positionN2(1) ~= r4(1) && Estimated_positionN2(2) ~= r4(2))
    possible_solutions(:, end+1) = Estimated_positionN2';
end

if (Estimated_positionN3(1) ~= 0 && Estimated_positionN3(2) ~= 0 && Estimated_positionN3(1) ~= r4(1) && Estimated_positionN3(2) ~= r4(2))
    possible_solutions(:, end+1) = Estimated_positionN3';
end

if (Estimated_positionN4(1) ~= 0 && Estimated_positionN4(2) ~= 0 && Estimated_positionN4(1) ~= r4(1) && Estimated_positionN4(2) ~= r4(2))
    possible_solutions(:, end+1) = Estimated_positionN4';
end


possible_solutions( :, all(~possible_solutions,1) ) = [];
if isempty(possible_solutions)
    disp("Bad Read, estimated position will be incorrect")
    Estimated_position = Estimated_positionN1;
else
    Estimated_position(1) = mean(possible_solutions(1,:));   
    Estimated_position(2) = mean(possible_solutions(2,:));

end

% Calculate the error in X and Y coordinates
error_x = source_x - Estimated_position(1);
error_y = source_y - Estimated_position(2);

% Calculate the combined error using Euclidean distance
combined_error = sqrt(error_x^2 + error_y^2);

% Display the combined error
fprintf("Combined Error: %.3f\n", combined_error);

fprintf("Source position: X = %.3f  Y = %.3f\n",source_x,source_y);
fprintf("Triangulated source position estimation:  X = %.3f  Y = %.3f\n",Estimated_position(1),Estimated_position(2));
fprintf("Individual errors:  X = %.3f  Y = %.3f\n",error_x,error_y);
fprintf("Combined errors = %.3f\n",combined_error);
app.CoordinateDisplayTextArea.Value = sprintf("Actual source position: X = %.4f, Y = %.4f\nLookup table estimation: X = %.4f, Y = %.4f", source_x, source_y, Estimated_position(1), Estimated_position(2));
pause(1);

          % Define the grid intervals
            x_min = 0;
            x_max = 0.8;
            y_min = 0;
            y_max = 0.5;
            x_intervals = 0.1;
            y_intervals = 0.1;

            % Create the x and y values for the grid
            x_values = x_min:x_intervals:x_max;
            y_values = y_min:y_intervals:y_max;

            % Plot the grid lines
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            xticks(app.UIAxes, x_values);
            yticks(app.UIAxes, y_values);

            % Plot circles at microphone positions
            microphone_positions = [
                0, 0;
                0, 0.5;
                0.8, 0;
                0.8, 0.5
            ];
            microphone_colors = ['r', 'g', 'b', 'm'];
            microphone_radius = 1e-2;

            for i = 1:size(microphone_positions, 1)
                x = microphone_positions(i, 1);
                y = microphone_positions(i, 2);
                color = microphone_colors(i);
                
                % Draw circle
                t = linspace(0, 2*pi, 100);
                x_circle = microphone_radius * cos(t) + x;
                y_circle = microphone_radius * sin(t) + y;
                plot(app.UIAxes, x_circle, y_circle, 'Color', color, 'LineWidth', 2);
            end

            % Plot a circle at the estimated position
            %estimated_position = [0.4, 0.3];  % Replace with your estimated position
            estimated_position_color = 'c';
            estimated_position_radius = 1e-2;
            
            % Draw circle at estimated position
            t = linspace(0, 2*pi, 100);
            x_circle = estimated_position_radius * cos(t) + Estimated_position(1);
            y_circle = estimated_position_radius * sin(t) + Estimated_position(2);
            plot(app.UIAxes, x_circle, y_circle, 'Color', estimated_position_color, 'LineWidth', 2);

            hold(app.UIAxes, 'off');
        

function [LUT] = Generate_LUT(R1, R2, R3, R4,res)

  % Define the x and y limits based on the microphone positions
    x_min = min([R1(1), R2(1), R3(1), R4(1)]);
    x_max = max([R1(1), R2(1), R3(1), R4(1)]);
    y_min = min([R1(2), R2(2), R3(2), R4(2)]);
    y_max = max([R1(2), R2(2), R3(2), R4(2)]);
    
    % Calculate the number of points in the lookup table
    num_points_x = round((x_max - x_min) * res);
    num_points_y = round((y_max - y_min) * res);
    num_points = num_points_x * num_points_y;
    
    % Initialize the lookup table
    LUT = zeros(num_points, 8);
    
    % Initialize the count
    count = 1;
    
    for i = 1:num_points_x
        for j = 1:num_points_y
            % Calculate the x and y values for this point
            x = x_min + (i - 1) / res;
            y = y_min + (j - 1) / res;
            
            % Calculate the time differences for each microphone
            TOA_R1 = sqrt((R1(1) - x)^2 + (R1(2) - y)^2) / 343;
            TOA_R2 = sqrt((R2(1) - x)^2 + (R2(2) - y)^2) / 343;
            TOA_R3 = sqrt((R3(1) - x)^2 + (R3(2) - y)^2) / 343;
            TOA_R4 = sqrt((R4(1) - x)^2 + (R4(2) - y)^2) / 343;
            
            % Calculate the time differences between microphones
            td21 = TOA_R2 - TOA_R1;
            td31 = TOA_R3 - TOA_R1;
            td41 = TOA_R4 - TOA_R1;
            td32 = TOA_R3 - TOA_R2;
            td42 = TOA_R4 - TOA_R2;
            td43 = TOA_R4 - TOA_R3;
            
            % Store the values in the lookup table
            LUT(count, 1) = td21;
            LUT(count, 2) = td31;
            LUT(count, 3) = td41;
            LUT(count, 4) = td32;
            LUT(count, 5) = td42;
            LUT(count, 6) = td43;
            LUT(count, 7) = x;
            LUT(count, 8) = y;
            
            % Increment the count
            count = count + 1;
        end
    end

%{
    max_x = max(max(max(R1(1),R2(1)),R3(1)),R4(1));
    max_y = max(max(max(R1(2),R2(2)),R3(2)),R4(2));
    count = 1;
    display(max_x*res)
    display(max_y*res)
    LUT = zeros(round(max_x*res*max_y*res),8);

    for i = 0:max_x*res
        for j=0:max_y*res
            S = [i/res j/res];
            
            TOA_R1=  sqrt((R1(1)-S(1))^2+(R1(2)-S(2))^2)/343;
            TOA_R2=  sqrt((R2(1)-S(1))^2+(R2(2)-S(2))^2)/343;
            TOA_R3=  sqrt((R3(1)-S(1))^2+(R3(2)-S(2))^2)/343;
            TOA_R4=  sqrt((R4(1)-S(1))^2+(R4(2)-S(2))^2)/343;
          
            
            td21 = TOA_R2 - TOA_R1;
            td31 = TOA_R3 - TOA_R1;
            td41 = TOA_R4 - TOA_R1;
            td32 = TOA_R3 - TOA_R2;
            td42 = TOA_R4 - TOA_R2;
            td43 = TOA_R4 - TOA_R3;

            LUT(count,1) = td21;
            LUT(count,2) = td31;
            LUT(count,3) = td41;
            LUT(count,4) = td32;
            LUT(count,5) = td42;
            LUT(count,6) = td43;
            LUT(count,7) = S(1);
            LUT(count,8) = S(2);
            count = count+1;
        end
    end
%}

end


function [Estimated_positionN1, Estimated_positionN2, Estimated_positionN3, Estimated_positionN4] = triangulate(t21, t31, t41, t32, t42, t43, LUT)

    td_simN1 = LUT(:, 4:6);
    td_simN2 = [LUT(:, 2), LUT(:, 3), LUT(:, 6)];
    td_simN3 = [LUT(:, 1), LUT(:, 3), LUT(:, 5)];
    td_simN4 = [LUT(:, 1), LUT(:, 2), LUT(:, 4)];

    % Define individual time differences
    td_arrN1_1 = t32;
    td_arrN1_2 = t42;
    td_arrN1_3 = t43;

    td_arrN2_1 = t31;
    td_arrN2_2 = t41;
    td_arrN2_3 = t43;

    td_arrN3_1 = t21;
    td_arrN3_2 = t41;
    td_arrN3_3 = t42;

    td_arrN4_1 = t21;
    td_arrN4_2 = t31;
    td_arrN4_3 = t32;


% Calculate time differences using bsxfun
diff_arrN1 = bsxfun(@minus, [td_simN1(:, 1), td_simN1(:, 2), td_simN1(:, 3)], [td_arrN1_1, td_arrN1_2, td_arrN1_3]);
diff_arrN2 = bsxfun(@minus, [td_simN2(:, 1), td_simN2(:, 2), td_simN2(:, 3)], [td_arrN2_1, td_arrN2_2, td_arrN2_3]);
diff_arrN3 = bsxfun(@minus, [td_simN3(:, 1), td_simN3(:, 2), td_simN3(:, 3)], [td_arrN3_1, td_arrN3_2, td_arrN3_3]);
diff_arrN4 = bsxfun(@minus, [td_simN4(:, 1), td_simN4(:, 2), td_simN4(:, 3)], [td_arrN4_1, td_arrN4_2, td_arrN4_3]);


    [~, IN1] = min(sum(abs(diff_arrN1) .* 2, 2));
    [~, IN2] = min(sum(abs(diff_arrN2) .* 2, 2));
    [~, IN3] = min(sum(abs(diff_arrN3) .* 2, 2));
    [~, IN4] = min(sum(abs(diff_arrN4) .* 2, 2));

  
Estimated_positionN1 = LUT(IN1, [7, 8]);
Estimated_positionN2 = LUT(IN2, [7, 8]);
Estimated_positionN3 = LUT(IN3, [7, 8]);
Estimated_positionN4 = LUT(IN4, [7, 8]);

end

        end

        % Callback function
        function Button3Pushed(app, event)
             app.CoordinateDisplayTextArea.Value = num2str(231);
             pause(1);
        end

        % Button pushed function: InitializeGridButton
        function InitializeGridButtonPushed(app, event)
               %Update

        % Get the value from the Numeric Edit Field
        mic1_x = app.XvalueEditField.Value;
        mic1_y = app.YvalueEditField.Value;
        mic2_x = app.XvalueEditField_2.Value;
        mic2_y = app.YvalueEditField_2.Value;
        mic3_x = app.XvalueEditField_3.Value;
        mic3_y = app.YvalueEditField_3.Value;
        mic4_x = app.XvalueEditField_4.Value;
        mic4_y = app.YvalueEditField_4.Value;

        mic_positions = [mic1_x, mic1_y;    % Position of mic1
                 mic2_x, mic2_y;   % Position of mic2
                 mic3_x, mic3_y;    % Position of mic3
                 mic4_x, mic4_y];% Position of mic4


        app.CoordinateDisplayTextArea.Value = "Grid initialized - ready to determine source";
        pause(1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DetermineSourceButton = uibutton(app.UIFigure, 'push');
            app.DetermineSourceButton.ButtonPushedFcn = createCallbackFcn(app, @DetermineSourceButtonPushed, true);
            app.DetermineSourceButton.Text = 'Determine Source ';
            app.CoordinateDisplayTextAreaLabel = uilabel(app.UIFigure);
            app.CoordinateDisplayTextAreaLabel.Text = 'Coordinate Display';
            app.CoordinateDisplayTextArea = uitextarea(app.UIFigure);
            app.CoordinateDisplayTextArea.Value = {'Please initialize grid'};
            app.AcousticTriangulationSimulatorLabel = uilabel(app.UIFigure);
            app.AcousticTriangulationSimulatorLabel.Text = 'Acoustic Triangulation Simulator';
            app.InitializeGridButton = uibutton(app.UIFigure, 'push');
            app.InitializeGridButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeGridButtonPushed, true);
            app.InitializeGridButton.Text = 'Initialize Grid';
            app.XvalueEditFieldLabel = uilabel(app.UIFigure);
            app.XvalueEditFieldLabel.Text = 'X-value';
            app.XvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.YvalueEditFieldLabel = uilabel(app.UIFigure);
            app.YvalueEditFieldLabel.Text = 'Y-value';
            app.YvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.XvalueEditField_2Label = uilabel(app.UIFigure);
            app.XvalueEditField_2Label.Text = 'X-value';
            app.XvalueEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YvalueEditField_2Label = uilabel(app.UIFigure);
            app.YvalueEditField_2Label.Text = 'Y-value';
            app.YvalueEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.XvalueEditField_3Label = uilabel(app.UIFigure);
            app.XvalueEditField_3Label.Text = 'X-value';
            app.XvalueEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.YvalueEditField_3Label = uilabel(app.UIFigure);
            app.YvalueEditField_3Label.Text = 'Y-value';
            app.YvalueEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.XvalueEditField_4Label = uilabel(app.UIFigure);
            app.XvalueEditField_4Label.Text = 'X-value';
            app.XvalueEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.YvalueEditField_4Label = uilabel(app.UIFigure);
            app.YvalueEditField_4Label.Text = 'Y-value';
            app.YvalueEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.Microphone1PositionLabel = uilabel(app.UIFigure);
            app.Microphone1PositionLabel.Text = 'Microphone 1 Position:';
            app.Microphone2PositionLabel = uilabel(app.UIFigure);
            app.Microphone2PositionLabel.Text = 'Microphone 2 Position:';
            app.Microphone3PositionLabel = uilabel(app.UIFigure);
            app.Microphone3PositionLabel.Text = 'Microphone 3 Position:';
            app.Microphone4PositionLabel = uilabel(app.UIFigure);
            app.Microphone4PositionLabel.Text = 'Microphone 4 Position:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Grid_Simulation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end