classdef Acoustic_Simulator_Updated < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        noiseSlider                matlab.ui.control.Slider
        noiseSliderLabel           matlab.ui.control.Label
        refreshButton              matlab.ui.control.Button
        YvalueEditField_4          matlab.ui.control.NumericEditField
        YvalueEditField_4Label     matlab.ui.control.Label
        XvalueEditField_4          matlab.ui.control.NumericEditField
        XvalueEditField_4Label     matlab.ui.control.Label
        YvalueEditField_3          matlab.ui.control.NumericEditField
        YvalueEditField_3Label     matlab.ui.control.Label
        XvalueEditField_3          matlab.ui.control.NumericEditField
        XvalueEditField_3Label     matlab.ui.control.Label
        YvalueEditField_2          matlab.ui.control.NumericEditField
        YvalueEditField_2Label     matlab.ui.control.Label
        XvalueEditField_2          matlab.ui.control.NumericEditField
        XvalueEditField_2Label     matlab.ui.control.Label
        YvalueEditField            matlab.ui.control.NumericEditField
        YvalueEditFieldLabel       matlab.ui.control.Label
        XvalueEditField            matlab.ui.control.NumericEditField
        XvalueEditFieldLabel       matlab.ui.control.Label
        CoordinateDisplayTextArea  matlab.ui.control.TextArea
        InformationDisplayTextAreaLabel  matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        Microphone4PositionLabel   matlab.ui.control.Label
        Microphone3PositionLabel   matlab.ui.control.Label
        Microphone2PositionLabel   matlab.ui.control.Label
        Microphone1PositionLabel   matlab.ui.control.Label
        InitializeGridButton       matlab.ui.control.Button
        AcousticTriangulationSimulatorLabel  matlab.ui.control.Label
        DetermineSourceButton      matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        % Default grid size if user does not define own locations of microphones before determining coordinates
        mic_positions = [0, 0;      % Position of mic1
                 0, 0.5;    % Position of mic2
                 0.8, 0;    % Position of mic3
                 0.8, 0.5]; % Position of mic4
    end
    
    properties (Access = private)
        gridLengthX = 0.8;
    end
    
    properties (Access = public)
        gridLengthY = 0.5;
    end

    properties (Access=public)
        noiselevel = 0.4;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonValueChanged(app, event)
            value = app.InitializeGridButton.Value;
            
            
        end

        % Button pushed function: DetermineSourceButton
        function DetermineSourceButtonPushed(app, event)
sampling_rate = 48000; % Set a sampling rate in Hertz
prop_speed = 343; % Speed of sound value in m/s

Lookup = Create_LTable(100,app.mic_positions(1,:),app.mic_positions(2,:),app.mic_positions(3,:),app.mic_positions(4,:));     % Create a lookup table by passing through the mic positions

source_position = [app.gridLengthX*rand(), app.gridLengthY*rand()];


% Generation of the chirp source signal
   hchirp = dsp.Chirp( ...
    'InitialFrequency', 0,...
    'TargetFrequency', 10, ...
    'TargetTime', 10, ...
    'SweepTime', 100, ...
    'SampleRate', 50, ...
    'SamplesPerFrame', 500);

   chirpData = (step(hchirp))';

signal = chirpData;


t = (0:1/sampling_rate:(length(signal) - 1) / sampling_rate);

% Euclidean distance formula used to calculate the physical distance between 2 points (source to each mic) in meters - stored in array 
distance_to_mic = sqrt(sum((app.mic_positions - source_position).^2, 2));


% Calculate delays for each microphone
perfect_timeDelays = distance_to_mic/prop_speed;
delays = round((perfect_timeDelays) * sampling_rate);

sigDelay = zeros(4, length(signal) + max(delays));


% Add noise (Gaussian) to the delayed signals
app.noiselevel = app.noiseSlider.Value/10;
[sigNoise, noise] = AddNoise(sigDelay, app.noiselevel);

% Filter the noisy signals
filtSignals = SignalFilter(sigNoise);

delSig= zeros(4, length(signal) + max(delays));
% Apply delays to each microphone's signal
for i = 1:4
    % Calculate the index to start the delayed signal
    count = delays(i) + 1; % Add 1 to account for MATLAB's 1-based indexing syntax
    
    % Apply the delay to the signal channel
    delSig(i, count:count + length(signal) - 1) = signal;
end

signal1 = delSig(1, :);  % Extract the first delayed signal into 'signal1'
signal2 = delSig(2, :);  % Extract the second delayed signal into 'signal2'
signal3 = delSig(3, :);  % Extract the third delayed signal into 'signal3'
signal4 = delSig(4, :);  % Extract the fourth delayed signal into 'signal4'

noise1 = noise(1, :);  
noise2 = noise(2, :);  
noise3 = noise(3, :); 
noise4 = noise(4, :); 

signal_power1 = sum(abs(signal1).^2);
signal_power2 = sum(abs(signal2).^2);
signal_power3 = sum(abs(signal3).^2);
signal_power4 = sum(abs(signal4).^2);

noise_power1 =  sum(abs(noise1).^2);
noise_power2 =  sum(abs(noise2).^2);
noise_power3 =  sum(abs(noise3).^2);
noise_power4 =  sum(abs(noise4).^2);

SNR1 = 10 * log10(signal_power1 / noise_power1);
SNR2 = 10 * log10(signal_power2 / noise_power2);
SNR3 = 10 * log10(signal_power3 / noise_power3);
SNR4 = 10 * log10(signal_power4 / noise_power4);

figure;
subplot(5,1,1);
plot(t, signal);
title('Original Sound source - Chirp Signal');
for i = 1:4
    filtSignals(i, delays(i) + 1 : delays(i) + length(signal)) = signal;
    subplot(5, 1, i+1);
    plot(t, filtSignals(i, 1:length(signal)));
    title(['Received signal at Microphone ', num2str(i)]);
end

figure;
ToA_source2mic = zeros(1,4);
for i = 1:4
     % Cross correlation done on filtered microphone detected signals & original chirp signal
    [cross_corr, delay_array] = xcorr(filtSignals(i, :),signal);

    % Code to find the maximum peak in the cross-correlation (avoiding negative peaks)
    [max_peak, max_peak_idx] = max(cross_corr);

    % Check if the maximum peak is above a user-defined threshold
    if max_peak > 0.05 % Adjust this threshold value as needed
        % Calculate the time difference using the index of the maximum peak
        ToA_source2mic(i) = (delay_array(max_peak_idx) / sampling_rate);
    else
        % Handle the case when there is no valid peak that's found
        ToA_source2mic(i) = NaN; % or assign some default value
    end
    
    subplot(4, 1, i);
    plot(delay_array / sampling_rate, cross_corr);
    title(['Cross-correlation with Microphone ', num2str(i)]);
    xlabel('Time (s)');
    ylabel('Cross-correlation');
    grid on;
end

Time_diff21 = ToA_source2mic(2) - ToA_source2mic(1);
Time_diff31 = ToA_source2mic(3) - ToA_source2mic(1);
Time_diff41 = ToA_source2mic(4) - ToA_source2mic(1);
Time_diff32 = ToA_source2mic(3) - ToA_source2mic(2);
Time_diff42 = ToA_source2mic(4) - ToA_source2mic(2);
Time_diff43 = ToA_source2mic(4) - ToA_source2mic(3);


[Estimation1, Estimation2, Estimation3, Estimation4]   = find_position(Lookup,Time_diff21,Time_diff31,Time_diff41,Time_diff32,Time_diff42,Time_diff43);


estimations = [];

% Compare position estimations
if (Estimation1(1) ~= 0 && Estimation1(2) ~= 0 && Estimation1(1) ~= app.mic_positions(4,1) && Estimation1(2) ~= app.mic_positions(4,2))
    estimations(:, end+1) = Estimation1;
end

if (Estimation2(1) ~= app.mic_positions(1,1) && Estimation2(2) ~= app.mic_positions(1,1) && Estimation2(1) ~= app.mic_positions(4,1) && Estimation2(2) ~= app.mic_positions(4,2))
    estimations(:, end+1) = Estimation2';
end

if (Estimation3(1) ~= 0 && Estimation3(2) ~= 0 && Estimation3(1) ~= app.mic_positions(4,1) && Estimation3(2) ~= app.mic_positions(4,2))
    estimations(:, end+1) = Estimation3';
end

if (Estimation4(1) ~= 0 && Estimation4(2) ~= 0 && Estimation4(1) ~= app.mic_positions(4,1) && Estimation4(2) ~= app.mic_positions(4,2))
    estimations(:, end+1) = Estimation4';
end

estimations( :, all(~estimations,1) ) = [];
if isempty(estimations)
    disp("Estimation error - inaccurate triangulated position")
    Triangulation_estimate = Estimation1;
else
    Triangulation_estimate(1) = mean(estimations(1,:));   
    Triangulation_estimate(2) = mean(estimations(2,:));

end

% Calculate the error in X and Y coordinates
error_x = source_position(1) - Triangulation_estimate(1);
error_y = source_position(2) - Triangulation_estimate(2);

% Calculate the combined error 
combined_error = sqrt(error_x^2 + error_y^2);


% Create an empty string to accumulate the text information
displayText = "";

displayText = append(displayText, sprintf("Source Position:\n X: %.4f\n Y: %.4f\n", source_position(1), source_position(2)));
displayText = append(displayText, sprintf("Triangulated Source Position Estimation:\n X: %.4f\n Y: %.4f\n", Triangulation_estimate(1), Triangulation_estimate(2)));
displayText = append(displayText, sprintf("Combined Error: %.4f\n", combined_error));
displayText = append(displayText, sprintf("Individual Errors:\n X: %.4f\n Y: %.4f\n", error_x, error_y));
displayText = append(displayText, sprintf("SNR1: %.4f\n", SNR1));
displayText = append(displayText, sprintf("SNR2: %.4f\n", SNR2));
displayText = append(displayText, sprintf("SNR3: %.4f\n", SNR3));
displayText = append(displayText, sprintf("SNR4: %.4f\n", SNR4));

% Update the CoordinateDisplayTextArea with the concatenated text
app.CoordinateDisplayTextArea.Value = displayText;


          % Define the grid intervals
            x_min = 0;
            x_max = app.gridLengthX;
            y_min = 0;
            y_max = app.gridLengthY;
            x_intervals = 0.1;
            y_intervals = 0.1;

            % Create the x and y values for the grid
            x_values = x_min:x_intervals:x_max;
            y_values = y_min:y_intervals:y_max;

            % Plotting of the grid lines
hold(app.UIAxes, 'on');
grid(app.UIAxes, 'on');
xticks(app.UIAxes, x_values);
yticks(app.UIAxes, y_values);

microphone_colors = 'k';  % Change to black colour for mics
microphone_radius = 1e-2;

for i = 1:size(app.mic_positions, 1)
    x = app.mic_positions(i, 1);
    y = app.mic_positions(i, 2);
    color = microphone_colors;
    
    % Draw circle
    t = linspace(0, 2*pi, 100);
    x_circle = microphone_radius * cos(t) + x;
    y_circle = microphone_radius * sin(t) + y;
    plot(app.UIAxes, x_circle, y_circle, 'Color', color, 'LineWidth', 2);
end

% Plot a circle at the estimated source position
estimated_position_color = 'r';  % Change to red colour for mics
estimated_position_radius = 1e-2;


% Draw circle at estimated source position
t = linspace(0, 2*pi, 100);
x_circle = estimated_position_radius * cos(t) + Triangulation_estimate(1);
y_circle = estimated_position_radius * sin(t) + Triangulation_estimate(2);
plot(app.UIAxes, x_circle, y_circle, 'Color', estimated_position_color, 'LineWidth', 2);


% Add a red crosshair inside the source position circle
crosshair_length = 3 * estimated_position_radius;
x_crosshair = [Triangulation_estimate(1) - crosshair_length / 2, Triangulation_estimate(1) + crosshair_length / 2];
y_crosshair = [Triangulation_estimate(2), Triangulation_estimate(2)];
plot(app.UIAxes, x_crosshair, y_crosshair, 'Color', 'bl', 'LineWidth', 1);
x_crosshair = [Triangulation_estimate(1), Triangulation_estimate(1)];
y_crosshair = [Triangulation_estimate(2) - crosshair_length / 2, Triangulation_estimate(2) + crosshair_length / 2];
plot(app.UIAxes, x_crosshair, y_crosshair, 'Color', 'bl', 'LineWidth', 1);


hold(app.UIAxes, 'off');


function [LTable] = Create_LTable(table_resolution,mic1, mic2, mic3, mic4)

    Incrementor = 1;

  % Setting the x and y table limits based on the microphone positions
    x_min = min([mic1(1), mic2(1), mic3(1), mic4(1)]);
    x_max = max([mic1(1), mic2(1), mic3(1), mic4(1)]);
    y_min = min([mic1(2), mic2(2), mic3(2), mic4(2)]);
    y_max = max([mic1(2), mic2(2), mic3(2), mic4(2)]);
    
    % Calculation of the number of points for the table - based on the user defined table resolution
    num_points_x = round((x_max - x_min) * table_resolution);
    num_points_y = round((y_max - y_min) * table_resolution);
    num_points = num_points_x * num_points_y;
    
    % Initialize the lookup table
    LTable = zeros(num_points, 8);
    
    for i = 1:num_points_x
        for j = 1:num_points_y
            % Calculate the x and y values for this point
            x = x_min + (i - 1) / table_resolution;
            y = y_min + (j - 1) / table_resolution;
            
            % Calculate the time differences for each microphone
            TOA1 = sqrt((mic1(1) - x)^2 + (mic1(2) - y)^2) / 343;
            TOA2= sqrt((mic2(1) - x)^2 + (mic2(2) - y)^2) / 343;
            TOA3 = sqrt((mic3(1) - x)^2 + (mic3(2) - y)^2) / 343;
            TOA4 = sqrt((mic4(1) - x)^2 + (mic4(2) - y)^2) / 343;
            
            % Calculate the time differences between microphones
            TDoA21 = TOA2 - TOA1;
            TDoA31 = TOA3 - TOA1;
            TDoA41 = TOA4 - TOA1;
            TDoA32 = TOA3 - TOA2;
            TDoA42 = TOA4 - TOA2;
            TDoA43 = TOA4 - TOA3;
            
            % Store the values in the lookup table
            LTable(Incrementor, 1) = TDoA21;
            LTable(Incrementor, 2) = TDoA31;
            LTable(Incrementor, 3) = TDoA41;
            LTable(Incrementor, 4) = TDoA32;
            LTable(Incrementor, 5) = TDoA42;
            LTable(Incrementor, 6) = TDoA43;
            LTable(Incrementor, 7) = x;
            LTable(Incrementor, 8) = y;
            
            % Increment the counter
            Incrementor = Incrementor + 1;
        end
    end
end


function [Estimation1, Estimation2, Estimation3, Estimation4] = find_position(LTable,t21, t31, t41, t32, t42, t43)

    td1 = LTable(:, 4:6);
    td2 = [LTable(:, 2), LTable(:, 3), LTable(:, 6)];
    td3 = [LTable(:, 1), LTable(:, 3), LTable(:, 5)];
    td4 = [LTable(:, 1), LTable(:, 2), LTable(:, 4)];

    % Created variables for the individual time differences
    td_N1_1 = t32;
    td_N1_2 = t42;
    td_N1_3 = t43;

    td_N2_1 = t31;
    td_N2_2 = t41;
    td_N2_3 = t43;

    td_N3_1 = t21;
    td_N3_2 = t41;
    td_N3_3 = t42;

    td_N4_1 = t21;
    td_N4_2 = t31;
    td_N4_3 = t32;


% Calculated the time differences using bsxfun function
diff1 = bsxfun(@minus, [td1(:, 1), td1(:, 2), td1(:, 3)], [td_N1_1, td_N1_2, td_N1_3]);
diff2 = bsxfun(@minus, [td2(:, 1), td2(:, 2), td2(:, 3)], [td_N2_1, td_N2_2, td_N2_3]);
diff3 = bsxfun(@minus, [td3(:, 1), td3(:, 2), td3(:, 3)], [td_N3_1, td_N3_2, td_N3_3]);
diff4 = bsxfun(@minus, [td4(:, 1), td4(:, 2), td4(:, 3)], [td_N4_1, td_N4_2, td_N4_3]);


    [~, IN1] = min(sum(abs(diff1) .* 2, 2));
    [~, IN2] = min(sum(abs(diff2) .* 2, 2));
    [~, IN3] = min(sum(abs(diff3) .* 2, 2));
    [~, IN4] = min(sum(abs(diff4) .* 2, 2));

  
Estimation1 = LTable(IN1, [7, 8]);
Estimation2 = LTable(IN2, [7, 8]);
Estimation3 = LTable(IN3, [7, 8]);
Estimation4 = LTable(IN4, [7, 8]);

end

%Function to add Gaussian white noise to the microphone-received signals(according to a user-defined level of noise)
 function [noisySig, noiseLvl] = AddNoise(delayedSig, level)
noiseLvl = level*randn(size(delayedSig));
noisySig = delayedSig + noiseLvl;
end

%Function to filter the noisy signals - using the smoothdata function modelling a Gaussian filter
function filteredSig = SignalFilter(noisySig)
filteredSig = smoothdata(noisySig,'gaussian');
end

        end

        % Callback function
        function Button3Pushed(app, event)
             app.CoordinateDisplayTextArea.Value = num2str(231);
             pause(1);
        end

        % Button pushed function: InitializeGridButton
        function InitializeGridButtonPushed(app, event)
               %Update
        % Get the value from the Numeric Edit Field
        mic1_x = app.XvalueEditField.Value;
        mic1_y = app.YvalueEditField.Value;
        mic2_x = app.XvalueEditField_2.Value;
        mic2_y = app.YvalueEditField_2.Value;
        mic3_x = app.XvalueEditField_3.Value;
        mic3_y = app.YvalueEditField_3.Value;
        mic4_x = app.XvalueEditField_4.Value;
        mic4_y = app.YvalueEditField_4.Value;
        
        app.mic_positions = [mic1_x , mic1_y;      % Position of mic1
                 mic2_x, mic2_y ;    % Position of mic2
                 mic3_x , mic3_y ;    % Position of mic3
                 mic4_x , mic4_y ]; % Position of mic4

        app.gridLengthX = mic4_x;
        app.gridLengthY = mic4_y;

        app.CoordinateDisplayTextArea.Value = "Grid initialized - ready to determine source";
        pause(1);
        end

        % Button pushed function: refreshButton
        function refreshButtonPushed(app, event)
           % Clear the grid by removing plotted objects
    cla(app.UIAxes);

          % Redraw the grid lines (if needed)
    x_min = 0;
    x_max = 1; % Updated x_max to 0.8
    y_min = 0;
    y_max = 1; % Updated y_max to 0.5
    x_intervals = 0.1;
    y_intervals = 0.1;
    x_values = x_min:x_intervals:x_max;
    y_values = y_min:y_intervals:y_max;

    % Plotting of the grid lines
    hold(app.UIAxes, 'on');
    grid(app.UIAxes, 'on');
    xticks(app.UIAxes, x_values);
    yticks(app.UIAxes, y_values);

    
        end

        % Value changed function: noiseSlider
        function noiseSliderValueChanged(app, event)
             
            value = app.noiseSlider.Value;
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Simulated Grid')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DetermineSourceButton = uibutton(app.GridLayout, 'push');
            app.DetermineSourceButton.ButtonPushedFcn = createCallbackFcn(app, @DetermineSourceButtonPushed, true);
            app.DetermineSourceButton.Text = 'Determine Source ';
            app.AcousticTriangulationSimulatorLabel = uilabel(app.GridLayout);
            app.AcousticTriangulationSimulatorLabel.Text = 'Acoustic Triangulation Simulator';
            app.InitializeGridButton = uibutton(app.GridLayout, 'push');
            app.InitializeGridButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeGridButtonPushed, true);
            app.InitializeGridButton.Text = 'Initialize Grid';
            app.Microphone1PositionLabel = uilabel(app.GridLayout);
            app.Microphone1PositionLabel.Text = 'Microphone 1 Position:';
            app.Microphone2PositionLabel = uilabel(app.GridLayout);
            app.Microphone2PositionLabel.Text = 'Microphone 2 Position:';
            app.Microphone3PositionLabel = uilabel(app.GridLayout);
            app.Microphone3PositionLabel.Text = 'Microphone 3 Position:';
            app.Microphone4PositionLabel = uilabel(app.GridLayout);
            app.Microphone4PositionLabel.Text = 'Microphone 4 Position:';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = {'Project by:'; 'Joachim Gengan - GNGJOA003'; 'Kudzi Kadzimu - KDZKUD001'; 'Maisha Magavha - MGVMAI001'};
            app.InformationDisplayTextAreaLabel = uilabel(app.GridLayout);
            app.InformationDisplayTextAreaLabel.Text = 'Information Display';
            app.CoordinateDisplayTextArea = uitextarea(app.GridLayout);
            app.CoordinateDisplayTextArea.Value = {'Please initialize grid'};
            app.XvalueEditFieldLabel = uilabel(app.GridLayout);
            app.XvalueEditFieldLabel.Text = 'X-value';
            app.XvalueEditField = uieditfield(app.GridLayout, 'numeric');
            app.YvalueEditFieldLabel = uilabel(app.GridLayout);
            app.YvalueEditFieldLabel.Text = 'Y-value';
            app.YvalueEditField = uieditfield(app.GridLayout, 'numeric');
            app.XvalueEditField_2Label = uilabel(app.GridLayout);
            app.XvalueEditField_2Label.Text = 'X-value';
            app.XvalueEditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.XvalueEditField_2.Value = 0.6;
            app.YvalueEditField_2Label = uilabel(app.GridLayout);
            app.YvalueEditField_2Label.Text = 'Y-value';
            app.YvalueEditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.XvalueEditField_3Label = uilabel(app.GridLayout);
            app.XvalueEditField_3Label.Text = 'X-value';
            app.XvalueEditField_3 = uieditfield(app.GridLayout, 'numeric');
            app.YvalueEditField_3Label = uilabel(app.GridLayout);
            app.YvalueEditField_3Label.Text = 'Y-value';
            app.YvalueEditField_3 = uieditfield(app.GridLayout, 'numeric');
            app.YvalueEditField_3.Value = 0.3;
            app.XvalueEditField_4Label = uilabel(app.GridLayout);
            app.XvalueEditField_4Label.Text = 'X-value';
            app.XvalueEditField_4 = uieditfield(app.GridLayout, 'numeric');
            app.XvalueEditField_4.Value = 0.6;
            app.YvalueEditField_4Label = uilabel(app.GridLayout);
            app.YvalueEditField_4Label.Text = 'Y-value';
            app.YvalueEditField_4 = uieditfield(app.GridLayout, 'numeric');
            app.YvalueEditField_4.Value = 0.3;
            app.refreshButton = uibutton(app.GridLayout, 'push');
            app.refreshButton.ButtonPushedFcn = createCallbackFcn(app, @refreshButtonPushed, true);
            app.refreshButton.Text = 'refresh';
            app.noiseSliderLabel = uilabel(app.GridLayout);
            app.noiseSliderLabel.Text = 'noise';
            app.noiseSlider = uislider(app.GridLayout);
            app.noiseSlider.Limits = [0 5];
            app.noiseSlider.ValueChangedFcn = createCallbackFcn(app, @noiseSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Acoustic_Simulator_Updated

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end